classdef ContrastChangeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ScreenResolutionButtonGroup  matlab.ui.container.ButtonGroup
        TrainRigButton               matlab.ui.control.RadioButton
        PButton                      matlab.ui.control.RadioButton
        FullButton                   matlab.ui.control.RadioButton
        MonitorMQTTPanel             matlab.ui.container.Panel
        MQTTIPDropDown               matlab.ui.control.DropDown
        MQTTIPLabel                  matlab.ui.control.Label
        LampMonitor                  matlab.ui.control.Lamp
        SetScreenButton              matlab.ui.control.Button
        ScreenNoEditField            matlab.ui.control.NumericEditField
        ScreenNoEditFieldLabel       matlab.ui.control.Label
        AnimalInformationPanel       matlab.ui.container.Panel
        LampID                       matlab.ui.control.Lamp
        SavePathLabel                matlab.ui.control.Label
        SetIDButton                  matlab.ui.control.Button
        SavedatatoButton             matlab.ui.control.Button
        DateLabelValue               matlab.ui.control.Label
        DateLabel                    matlab.ui.control.Label
        AnimalIDEditField            matlab.ui.control.EditField
        AnimalIDEditFieldLabel       matlab.ui.control.Label
        ExperimentSetupPanel         matlab.ui.container.Panel
        RewardSizeEditField          matlab.ui.control.NumericEditField
        SizemsLabel                  matlab.ui.control.Label
        RewardLabel                  matlab.ui.control.Label
        LampExperiment               matlab.ui.control.Lamp
        SetExperimentButton          matlab.ui.control.Button
        Atelabel                     matlab.ui.control.Label
        ExpDurationminLabel          matlab.ui.control.Label
        HigherEditField              matlab.ui.control.NumericEditField
        HigherEditFieldLabel         matlab.ui.control.Label
        LowerEditField               matlab.ui.control.NumericEditField
        LowerEditFieldLabel          matlab.ui.control.Label
        VelocityTresholdsLabel       matlab.ui.control.Label
        ContraststepsEditField       matlab.ui.control.NumericEditField
        ContraststepsEditFieldLabel  matlab.ui.control.Label
        ImagelengthEditField         matlab.ui.control.NumericEditField
        ImagelengthEditFieldLabel    matlab.ui.control.Label
        NoTrialsEditField            matlab.ui.control.NumericEditField
        NoTrialsEditFieldLabel       matlab.ui.control.Label
        TexturesLamp                 matlab.ui.control.Lamp
        SavePathValueLabel           matlab.ui.control.Label
        STOPSAVEButton               matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        UploadTexturesButton         matlab.ui.control.Button
        TrialDurationplot            matlab.ui.control.UIAxes
        Lickplot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SaveRoot % Description
        rwrd_img
        Norwrd_img
        trials
        trial
        alpha
        img_len
        lwr_trsh
        hgh_trsh
        distToChange
        cntrst_dx
        rewardsize
        mqttip % Description
        ScreenNo % Description
        stopandsave = 0; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DateLabelValue.Text = datestr(floor(now), 'yyyy_mm_dd') ;
        end

        % Button pushed function: UploadTexturesButton
        function UploadTexturesButtonPushed(app, event)
            [app.rwrd_img,app.Norwrd_img] = UploadImages();
            app.TexturesLamp.Color = 'g';
        end

        % Button pushed function: SetExperimentButton
        function SetExperimentButtonPushed(app, event)
           [app.trials,app.trial,app.alpha,app.img_len,app.lwr_trsh,app.hgh_trsh,app.distToChange,app.cntrst_dx,aet] = ExperimentSetup(app.NoTrialsEditField.Value,...
                app.ImagelengthEditField.Value,app.ContraststepsEditField.Value,app.LowerEditField.Value,app.HigherEditField.Value);
            app.Atelabel.Text = num2str(round(aet,2));
            app.rewardsize = app.RewardSizeEditField.Value;
        end

        % Button pushed function: SavedatatoButton
        function SavedatatoButtonPushed(app, event)
            Path = uigetdir('D:\EXP');
            app.SavePathLabel.Text = Path; 
        end

        % Button pushed function: SetIDButton
        function SetIDButtonPushed(app, event)
            app.SaveRoot = fullfile(app.SavePathLabel.Text,strcat(app.AnimalIDEditField.Value,'_',app.DateLabelValue.Text,'.mat'));
            app.LampID.Color = 'g';
        end

        % Button pushed function: SetScreenButton
        function SetScreenButtonPushed(app, event)
            app.mqttip = app.MQTTIPDropDown.Value;
            app.ScreenNo = app.ScreenNoEditField.Value; 
            app.LampMonitor.Color = 'g';
        end

        % Button pushed function: STOPSAVEButton
        function STOPSAVEButtonPushed(app, event)
            app.stopandsave = 1;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            [screenNumber,~,~,grey] = PsychInit(app.ScreenNo); %add monitor selection
            app.rwrd_img = app.rwrd_img - grey;
            app.Norwrd_img = app.Norwrd_img - grey;
            %% MQTT Setup
            myMQTT=mqtt(app.mqttip); %add mqqt server selection
            LickSub = subscribe(myMQTT,'LickPort/','QoS',0);
            MoveSub = subscribe(myMQTT,'sphericalTreadmill/Data','QoS',0); 
            MoveMsgCount =  0;
            LickMsgCount =  0; 
            %% Experiment 
            try
                %% Texture making
                PsychImaging('PrepareConfiguration');
                PsychImaging('AddTask', 'General', 'FloatingPoint32BitIfPossible');
                [win, ~] = PsychImaging('OpenWindow', screenNumber,grey,[1920 0 1920*2-1 900*2-1]); % add resolution
                % rectsize =[top-left-x top-left-y bottom-right-x bottom-left-y]
                Screen('ColorRange', win, 1, 0);
                imagtex_reward = Screen('MakeTexture', win, app.rwrd_img , [], [], 1);
                imagtex_non_reward = Screen('MakeTexture', win, app.Norwrd_img , [], [], 1);
                
                %% Random texture selection
               [texture_toshow,Isrewarded] = RandomImgSelection(imagtex_reward,imagtex_non_reward);
                %% Experiment init
               [distance,SubjM,Licks] = ExperimentInit(app.trial,app.alpha,Isrewarded);
                %% Experiment run
                t0 = tic;
                while app.trial<=app.trials
                    
                    if app.stopandsave
                        save(app.SaveRoot,"SubjM","Licks");
                        break;
                    end
                    
                %% Modify Texture    
                    Screen('Blendfunction', win, GL_SRC_ALPHA, GL_ONE);
                    Screen('DrawTexture', win, texture_toshow, [], [], [], 1, app.alpha);
                    Screen('DrawingFinished', win);
                %% Read movement
                    pause(2/1000); 
                    [SubjM,MoveMsgCount] = ReadMov(MoveSub,MoveMsgCount);
                    combinedSpeed = sum(SubjM(end,1:2).^2)^0.5;
                %% Read licks       
                    pause(2/1000);  
                    [LickMsgCount, IsLicking] = ReadLick(LickSub, LickMsgCount);
                    Licks(end+1,:) = [app.trial distance app.alpha Isrewarded now];
                    %plot the distance in a trial in which a lick ocurred 
%                     if isLicking && Isrewarded
%                       scatter(app.Lickplot,Licks(end,2),Licks(end,1),'g') 
%                       drawnow; 
%                     elseif isLicking && ~IsRewarded
%                       scatter(app.Lickplot,Licks(end,2),Licks(end,1),'r')
%                       drawnow; 
%                     end
                    
                %% Change contrast (alpha) and show it to screen
                    if combinedSpeed > app.lwr_trsh && combinedSpeed < app.hgh_trsh % only change contrasts if inside tresholds
                       distance = distance + combinedSpeed; %distance accumulation
                       steps = distance/app.distToChange; % distance traveled is enough to change to next contrast step 
                       if distance <= (app.img_len/2)
                           % increase contrast
                            app.alpha = min(1, steps*app.cntrst_dx);               
                       elseif distance > (app.img_len/2) && distance <= app.img_len
                           % decrease contrast 
                            if IsLicking && Isrewarded, DeliverReward();  end
                            app.alpha = max(0, 1-abs(1-steps*app.cntrst_dx));
                       elseif distance > app.img_len
                           % new trial startup 
                            TrialDuration(app.trial) = toc(t0); % we can plot trial duration to monitor animal engagement with the task
                            %stem(app.TrialDuratonplot, TrialDuration) 
                            % drawnow;
                            distance = 0;
                            app.trial = app.trial + 1;
                            SubjM(end+1,:) = [0 0 0 distance app.trial now];
                            [texture_toshow,Isrewarded] = RandomImgSelection(imagtex_reward,imagtex_non_reward);
                            WaitSecs(2.5) % 100cm waiting time @ 40cm/s
                            t0 = tic;
                       end
                       Screen('Flip', win);
                    end
                end
                Screen('Flip', win);
                sca;
                return;
            catch
                sca;
                psychrethrow(psychlasterror);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lickplot = uiaxes(app.UIFigure);
            title(app.Lickplot, 'Licks')
            xlabel(app.Lickplot, 'Position')
            ylabel(app.Lickplot, 'Trial #')
            zlabel(app.Lickplot, 'Z')
            app.TrialDurationplot = uiaxes(app.UIFigure);
            title(app.TrialDurationplot, 'Trial Duration')
            xlabel(app.TrialDurationplot, 'Trial #')
            ylabel(app.TrialDurationplot, 'Time (s)')
            zlabel(app.TrialDurationplot, 'Z')
            app.UploadTexturesButton = uibutton(app.UIFigure, 'push');
            app.UploadTexturesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTexturesButtonPushed, true);
            app.UploadTexturesButton.Text = '4) Upload Textures';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPSAVEButton = uibutton(app.UIFigure, 'push');
            app.STOPSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSAVEButtonPushed, true);
            app.STOPSAVEButton.Text = 'STOP/SAVE';
            app.SavePathValueLabel = uilabel(app.UIFigure);
            app.SavePathValueLabel.Text = '';
            app.TexturesLamp = uilamp(app.UIFigure);
            app.ExperimentSetupPanel = uipanel(app.UIFigure);
            app.ExperimentSetupPanel.Title = '3) Experiment Setup';
            app.NoTrialsEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.NoTrialsEditFieldLabel.Text = 'No. Trials';
            app.NoTrialsEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.ImagelengthEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.ImagelengthEditFieldLabel.Text = 'Image length';
            app.ImagelengthEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.ContraststepsEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.ContraststepsEditFieldLabel.Text = 'Contrast steps';
            app.ContraststepsEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.VelocityTresholdsLabel = uilabel(app.ExperimentSetupPanel);
            app.VelocityTresholdsLabel.Text = 'Velocity Tresholds';
            app.LowerEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.LowerEditFieldLabel.Text = 'Lower ';
            app.LowerEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.LowerEditField.Value = 0.05;
            app.HigherEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.HigherEditFieldLabel.Text = 'Higher ';
            app.HigherEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.HigherEditField.Value = 0.2;
            app.ExpDurationminLabel = uilabel(app.ExperimentSetupPanel);
            app.ExpDurationminLabel.Text = 'Exp. Duration (min):';
            app.Atelabel = uilabel(app.ExperimentSetupPanel);
            app.Atelabel.Text = '';
            app.SetExperimentButton = uibutton(app.ExperimentSetupPanel, 'push');
            app.SetExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SetExperimentButtonPushed, true);
            app.SetExperimentButton.Text = 'Set';
            app.LampExperiment = uilamp(app.ExperimentSetupPanel);
            app.RewardLabel = uilabel(app.ExperimentSetupPanel);
            app.RewardLabel.Text = 'Reward';
            app.SizemsLabel = uilabel(app.ExperimentSetupPanel);
            app.SizemsLabel.Text = 'Size (ms)';
            app.RewardSizeEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.AnimalInformationPanel = uipanel(app.UIFigure);
            app.AnimalInformationPanel.Title = '1) Animal Information';
            app.AnimalIDEditFieldLabel = uilabel(app.AnimalInformationPanel);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.AnimalInformationPanel, 'text');
            app.DateLabel = uilabel(app.AnimalInformationPanel);
            app.DateLabel.Text = 'Date:';
            app.DateLabelValue = uilabel(app.AnimalInformationPanel);
            app.DateLabelValue.Text = '';
            app.SavedatatoButton = uibutton(app.AnimalInformationPanel, 'push');
            app.SavedatatoButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatoButtonPushed, true);
            app.SavedatatoButton.Text = 'Save data to:';
            app.SetIDButton = uibutton(app.AnimalInformationPanel, 'push');
            app.SetIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetIDButtonPushed, true);
            app.SetIDButton.Text = 'Set';
            app.SavePathLabel = uilabel(app.AnimalInformationPanel);
            app.SavePathLabel.Text = '';
            app.LampID = uilamp(app.AnimalInformationPanel);
            app.MonitorMQTTPanel = uipanel(app.UIFigure);
            app.MonitorMQTTPanel.Title = '2) Monitor & MQTT';
            app.ScreenNoEditFieldLabel = uilabel(app.MonitorMQTTPanel);
            app.ScreenNoEditFieldLabel.Text = 'Screen No.';
            app.ScreenNoEditField = uieditfield(app.MonitorMQTTPanel, 'numeric');
            app.SetScreenButton = uibutton(app.MonitorMQTTPanel, 'push');
            app.SetScreenButton.ButtonPushedFcn = createCallbackFcn(app, @SetScreenButtonPushed, true);
            app.SetScreenButton.Text = 'Set';
            app.LampMonitor = uilamp(app.MonitorMQTTPanel);
            app.MQTTIPLabel = uilabel(app.MonitorMQTTPanel);
            app.MQTTIPLabel.Text = 'MQTT IP: ';
            app.MQTTIPDropDown = uidropdown(app.MonitorMQTTPanel);
            app.MQTTIPDropDown.Items = {'''tcp://169.254.0.10''', '''tcp://127.0.0.1'''};
            app.MQTTIPDropDown.Value = '''tcp://169.254.0.10''';
            app.ScreenResolutionButtonGroup = uibuttongroup(app.UIFigure);
            app.ScreenResolutionButtonGroup.Title = 'Screen Resolution';
            app.FullButton = uiradiobutton(app.ScreenResolutionButtonGroup);
            app.FullButton.Text = 'Full';
            app.FullButton.Value = true;
            app.PButton = uiradiobutton(app.ScreenResolutionButtonGroup);
            app.PButton.Text = '2P';
            app.TrainRigButton = uiradiobutton(app.ScreenResolutionButtonGroup);
            app.TrainRigButton.Text = 'TrainRig';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContrastChangeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end